classdef app_lab9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TimeconstantEditField_2       matlab.ui.control.NumericEditField
        TimeconstantEditField_2Label  matlab.ui.control.Label
        C_wz2EditField                matlab.ui.control.NumericEditField
        C_wz2EditFieldLabel           matlab.ui.control.Label
        C_wz1EditField                matlab.ui.control.NumericEditField
        C_wz1EditFieldLabel           matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MyModel = 'laba8';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % загружаем модель
            load_system(app.MyModel);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % код кнопки
            my_sim_result = sim(app.MyModel);
            plot(app.UIAxes, my_sim_result.coord.Time,my_sim_result.coord.Data);
            plot(app.UIAxes2, my_sim_result.speed.Time,my_sim_result.speed.Data);
        end

        % Value changed function: TimeconstantEditField_2
        function TimeconstantEditField_2ValueChanged(app, event)
            value = "["+num2str(app.TimeconstantEditField_2.Value) + " 1]";
            set_param([app.MyModel '/TF'], 'Denominator',num2str(value));
        end

        % Value changed function: C_wz2EditField
        function C_wz2EditFieldValueChanged(app, event)
            value = app.C_wz2EditField.Value;
            set_param([app.MyModel '/Gain11'],'Gain', num2str(value));
           
        end

        % Value changed function: C_wz1EditField
        function C_wz1EditFieldValueChanged(app, event)
            value = app.C_wz1EditField.Value;
             set_param([app.MyModel '/gain10'],'Gain', num2str(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Изменеие координаты')
            xlabel(app.UIAxes2, 'Время')
            ylabel(app.UIAxes2, 'Координата')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Изменение скорости')
            xlabel(app.UIAxes, 'Время')
            ylabel(app.UIAxes, 'Скорость')
            zlabel(app.UIAxes, 'Z')
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.C_wz1EditFieldLabel = uilabel(app.GridLayout);
            app.C_wz1EditFieldLabel.Text = 'C_wz1';
            app.C_wz1EditField = uieditfield(app.GridLayout, 'numeric');
            app.C_wz1EditField.ValueChangedFcn = createCallbackFcn(app, @C_wz1EditFieldValueChanged, true);
            app.C_wz2EditFieldLabel = uilabel(app.GridLayout);
            app.C_wz2EditFieldLabel.Text = 'C_wz2';
            app.C_wz2EditField = uieditfield(app.GridLayout, 'numeric');
            app.C_wz2EditField.ValueChangedFcn = createCallbackFcn(app, @C_wz2EditFieldValueChanged, true);
            app.TimeconstantEditField_2Label = uilabel(app.GridLayout);
            app.TimeconstantEditField_2Label.Text = 'Time constant';
            app.TimeconstantEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.TimeconstantEditField_2.ValueChangedFcn = createCallbackFcn(app, @TimeconstantEditField_2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_lab9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end